classdef traffic < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Spread                        matlab.ui.control.Label
        Center                        matlab.ui.control.Label
        DataTypeLabel                 matlab.ui.control.Label
        DataTypeSwitch                matlab.ui.control.Switch
        MeasuresofCenterSpreadSwitch  matlab.ui.control.ToggleSwitch
        MeasuresofCenterSpreadSwitchLabel  matlab.ui.control.Label
        StreetApproachButtonGroup     matlab.ui.container.ButtonGroup
        Button_4                      matlab.ui.control.RadioButton
        Button_3                      matlab.ui.control.RadioButton
        Button_2                      matlab.ui.control.RadioButton
        Button_1                      matlab.ui.control.RadioButton
        Image                         matlab.ui.control.Image
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function func(app)
            %Load the data into the workspace
            traffic = readmatrix("trafficData.csv");

            %Find and compute Summary Statistics for each approach

                %Define variables for each approach by data type
                OneVehicles = traffic(3:end,2);
                OnePedestrians = traffic(3:end,3);
                TwoVehicles = traffic(3:end,4);
                TwoPedestrians = traffic(3:end,5);
                ThreeVehicles = traffic(3:end,6);
                ThreePedestrians = traffic(3:end,7);
                FourVehicles = traffic(3:end,8);
                FourPedestrians = traffic(3:end,9);
    
                %Compute the Mean of each approach
                OneVehiclesMean = mean(OneVehicles);
                OnePedestriansMean = mean(OnePedestrians);
                TwoVehiclesMean = mean(TwoVehicles);
                TwoPedestriansMean = mean(TwoPedestrians);
                ThreeVehiclesMean = mean(ThreeVehicles);
                ThreePedestriansMean = mean(ThreePedestrians);
                FourVehiclesMean = mean(FourVehicles);
                FourPedestriansMean = mean(FourPedestrians);
    
                %Compute the Standard Deviation of each approach
                OneVehiclesSD = std(OneVehicles);
                OnePedestriansSD = std(OnePedestrians);
                TwoVehiclesSD = std(TwoVehicles);
                TwoPedestriansSD = std(TwoPedestrians);
                ThreeVehiclesSD = std(ThreeVehicles);
                ThreePedestriansSD = std(ThreePedestrians);
                FourVehiclesSD = std(FourVehicles);
                FourPedestriansSD = std(FourPedestrians);
    
                %Compute the Median of each approach
                OneVehiclesMedian = median(OneVehicles);
                OnePedestriansMedian = median(OnePedestrians);
                TwoVehiclesMedian = median(TwoVehicles);
                TwoPedestriansMedian = median(TwoPedestrians);
                ThreeVehiclesMedian = median(ThreeVehicles);
                ThreePedestriansMedian = median(ThreePedestrians);
                FourVehiclesMedian = median(FourVehicles);
                FourPedestriansMedian = median(FourPedestrians);
    
                %Compute the IQR of each approach
                OneVehiclesIQR = iqr(OneVehicles);
                OnePedestriansIQR = iqr(OnePedestrians);
                TwoVehiclesIQR = iqr(TwoVehicles);
                TwoPedestriansIQR = iqr(TwoPedestrians);
                ThreeVehiclesIQR = iqr(ThreeVehicles);
                ThreePedestriansIQR = iqr(ThreePedestrians);
                FourVehiclesIQR = iqr(FourVehicles);
                FourPedestriansIQR = iqr(FourPedestrians);

            %Initialize x as a 1x44 categorical vector with all of the time stamps in the correct order.
            x = ["7:00","7:15","7:30","7:45","8:00","8:15","8:30","8:45","9:00","9:15","9:30","9:45","10:00","10:15","10:30","10:45","11:00","11:15","11:30","11:45","12:00","12:15","12:30","12:45","13:00","13:15","13:30","13:45","14:00","14:15","14:30","15:45","16:00","16:15","16:30","16:45","17:00","17:15","17:30","17:45","18:00","18:15","18:30","18:45"];
            x = categorical(x);
            x = reordercats(x,["7:00","7:15","7:30","7:45","8:00","8:15","8:30","8:45","9:00","9:15","9:30","9:45","10:00","10:15","10:30","10:45","11:00","11:15","11:30","11:45","12:00","12:15","12:30","12:45","13:00","13:15","13:30","13:45","14:00","14:15","14:30","15:45","16:00","16:15","16:30","16:45","17:00","17:15","17:30","17:45","18:00","18:15","18:30","18:45"])';

            %Intialize values of the interactive switches/buttons in the GUI
            selectedButton = app.StreetApproachButtonGroup.SelectedObject;
            value = app.DataTypeSwitch.Value;
            value2 = app.MeasuresofCenterSpreadSwitch.Value;
            
            %Plot the bar graph of each approach, conditioned on the data type selected by the switch, and then display the summary stats (center and spread) conditioned on the selected measures (Mean/STD or Median/IQR)
            switch selectedButton.Text
                
                %Street Approach 1: Saratoga St. West
                case "1"
                    switch value
                        %Vehicles
                        case "Vehicles"
                            %Barplot
                            bar(app.UIAxes,x,OneVehicles)
                            title(app.UIAxes,"Saratoga Street West Vehicles")
                            xlabel(app.UIAxes,"Time")
                            ylabel(app.UIAxes,"Vehicles Through Intersection")
                            %Summary Stats
                            switch value2
                                case "Mean/Standard Deviation"
                                    app.Center.Text = string("Mean: " + OneVehiclesMean);
                                    app.Spread.Text = string("SD: " + OneVehiclesSD);
                                case "Median/Interquartile Range"
                                    app.Center.Text = string("Median: " + OneVehiclesMedian);
                                    app.Spread.Text = string("IQR: " + OneVehiclesIQR);
                            end
                        %Pedestrians
                        case "Pedestrians"
                            %Barplot
                            bar(app.UIAxes,x,OnePedestrians)
                            title(app.UIAxes,"Saratoga Street West Pedestrians")
                            xlabel(app.UIAxes,"Time")
                            ylabel(app.UIAxes,"Pedestrians Through Intersection")
                            %Summary Stats
                            switch value2
                                case "Mean/Standard Deviation"
                                    app.Center.Text = string("Mean: " + OnePedestriansMean);
                                    app.Spread.Text = string("SD: " + OnePedestriansSD);
                                case "Median/Interquartile Range"
                                    app.Center.Text = string("Median: " + OnePedestriansMedian);
                                    app.Spread.Text = string("IQR: " + OnePedestriansIQR);
                            end
                    end
                
                %Street Approach 2: Bennington St. North
                case "2"
                    switch value
                        %Vehicles
                        case "Vehicles"
                            %Barplot
                            bar(app.UIAxes,x,TwoVehicles)
                            title(app.UIAxes,"Bennington Street North Vehicles")
                            xlabel(app.UIAxes,"Time")
                            ylabel(app.UIAxes,"Vehicles Through Intersection")
                            %Summary Stats
                            switch value2
                                case "Mean/Standard Deviation"
                                    app.Center.Text = string("Mean: " + TwoVehiclesMean);
                                    app.Spread.Text = string("SD: " + TwoVehiclesSD);
                                case "Median/Interquartile Range"
                                    app.Center.Text = string("Median: " + TwoVehiclesMedian);
                                    app.Spread.Text = string("IQR: " + TwoVehiclesIQR);
                            end
                        %Pedestrians
                        case "Pedestrians"
                            %Barplot
                            bar(app.UIAxes,x,TwoPedestrians)
                            title(app.UIAxes,"Bennington Street North Pedestrians")
                            xlabel(app.UIAxes,"Time")
                            ylabel(app.UIAxes,"Pedestrians Through Intersection")
                            %Summary Stats
                            switch value2
                                case "Mean/Standard Deviation"
                                    app.Center.Text = string("Mean: " + TwoPedestriansMean);
                                    app.Spread.Text = string("SD: " + TwoPedestriansSD);
                                case "Median/Interquartile Range"
                                    app.Center.Text = string("Median: " + TwoPedestriansMedian);
                                    app.Spread.Text = string("IQR: " + TwoPedestriansIQR);
                            end
                    end

                %Street Approach 3: Saratoga St. East
                case "3"
                    switch value
                        %Vehicles
                        case "Vehicles"
                            %Barplot
                            bar(app.UIAxes,x,ThreeVehicles)
                            title(app.UIAxes,"Saratoga Street East Vehicles")
                            xlabel(app.UIAxes,"Time")
                            ylabel(app.UIAxes,"Vehicles Through Intersection")
                            %SummaryStats
                            switch value2
                                case "Mean/Standard Deviation"
                                    app.Center.Text = string("Mean: " + ThreeVehiclesMean);
                                    app.Spread.Text = string("SD: " + ThreeVehiclesSD);
                                case "Median/Interquartile Range"
                                    app.Center.Text = string("Median: " + ThreeVehiclesMedian);
                                    app.Spread.Text = string("IQR: " + ThreeVehiclesIQR);
                            end
                        %Pedestrians
                        case "Pedestrians"
                            %Barplot
                            bar(app.UIAxes,x,ThreePedestrians)
                            title(app.UIAxes,"Saratoga Street East Pedestrians")
                            xlabel(app.UIAxes,"Time")
                            ylabel(app.UIAxes,"Pedestrians Through Intersection")
                            %Summary Stats
                            switch value2
                                case "Mean/Standard Deviation"
                                    app.Center.Text = string("Mean: " + ThreePedestriansMean);
                                    app.Spread.Text = string("SD: " + ThreePedestriansSD);
                                case "Median/Interquartile Range"
                                    app.Center.Text = string("Median: " + ThreePedestriansMedian);
                                    app.Spread.Text = string("IQR: " + ThreePedestriansIQR);
                            end
                    end

                %Street Approach 3: Bennington St. South
                case "4"
                    switch value
                        %Vehicles
                        case "Vehicles"
                            %Barplot
                            bar(app.UIAxes,x,FourVehicles)
                            title(app.UIAxes,"Bennington Street South Vehicles")
                            xlabel(app.UIAxes,"Time")
                            ylabel(app.UIAxes,"Vehicles Through Intersection")
                            %Summary Stats
                            switch value2
                                case "Mean/Standard Deviation"
                                    app.Center.Text = string("Mean: " + FourVehiclesMean);
                                    app.Spread.Text = string("SD: " + FourVehiclesSD);
                                case "Median/Interquartile Range"
                                    app.Center.Text = string("Median: " + FourVehiclesMedian);
                                    app.Spread.Text = string("IQR: " + FourVehiclesIQR);
                            end
                        %Pedestrians
                        case "Pedestrians"
                            %Barplot
                            bar(app.UIAxes,x,FourPedestrians)
                            title(app.UIAxes,"Bennington Street South Pedestrians")
                            xlabel(app.UIAxes,"Time")
                            ylabel(app.UIAxes,"Pedestrians Through Intersection")
                            %Summary Stats
                            switch value2
                                case "Mean/Standard Deviation"
                                    app.Center.Text = string("Mean: " + FourPedestriansMean);
                                    app.Spread.Text = string("SD: " + FourPedestriansSD);
                                case "Median/Interquartile Range"
                                    app.Center.Text = string("Median: " + FourPedestriansMedian);
                                    app.Spread.Text = string("IQR: " + FourPedestriansIQR);
                            end
                    end
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: StreetApproachButtonGroup
        function StreetApproachButtonGroupSelectionChanged(app, event)
            func(app)
        end

        % Value changed function: DataTypeSwitch
        function DataTypeSwitchValueChanged(app, event)
            func(app)
        end

        % Value changed function: MeasuresofCenterSpreadSwitch
        function MeasuresofCenterSpreadSwitchValueChanged(app, event)
            func(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Traffic Diagram Final.png');
            app.StreetApproachButtonGroup = uibuttongroup(app.UIFigure);
            app.StreetApproachButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StreetApproachButtonGroupSelectionChanged, true);
            app.StreetApproachButtonGroup.Title = 'Street Approach';
            app.Button_1 = uiradiobutton(app.StreetApproachButtonGroup);
            app.Button_1.Text = '1';
            app.Button_1.Value = true;
            app.Button_2 = uiradiobutton(app.StreetApproachButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.StreetApproachButtonGroup);
            app.Button_3.Text = '3';
            app.Button_4 = uiradiobutton(app.StreetApproachButtonGroup);
            app.Button_4.Text = '4';
            app.MeasuresofCenterSpreadSwitchLabel = uilabel(app.UIFigure);
            app.MeasuresofCenterSpreadSwitchLabel.Text = 'Measures of Center/Spread';
            app.MeasuresofCenterSpreadSwitch = uiswitch(app.UIFigure, 'toggle');
            app.MeasuresofCenterSpreadSwitch.Items = {'Median/Interquartile Range', 'Mean/Standard Deviation'};
            app.MeasuresofCenterSpreadSwitch.ValueChangedFcn = createCallbackFcn(app, @MeasuresofCenterSpreadSwitchValueChanged, true);
            app.MeasuresofCenterSpreadSwitch.Value = 'Median/Interquartile Range';
            app.DataTypeSwitch = uiswitch(app.UIFigure, 'slider');
            app.DataTypeSwitch.Items = {'Vehicles', 'Pedestrians'};
            app.DataTypeSwitch.ValueChangedFcn = createCallbackFcn(app, @DataTypeSwitchValueChanged, true);
            app.DataTypeSwitch.Value = 'Vehicles';
            app.DataTypeLabel = uilabel(app.UIFigure);
            app.DataTypeLabel.Text = 'Data Type';
            app.Center = uilabel(app.UIFigure);
            app.Center.Text = 'Center';
            app.Spread = uilabel(app.UIFigure);
            app.Spread.Text = 'Spread';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = traffic

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end